classdef SpectralM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        ProjectTab                      matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        MeasurementsPanel               matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        tblMeasurements                 matlab.ui.control.Table
        btnDeleteMeasurement            matlab.ui.control.Button
        ProjectManagementLabel          matlab.ui.control.Label
        PlottingPanel                   matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        btnPlot                         matlab.ui.control.Button
        xaxisDropDownLabel              matlab.ui.control.Label
        optXaxis                        matlab.ui.control.DropDown
        yaxisDropDownLabel              matlab.ui.control.Label
        optYaxis                        matlab.ui.control.DropDown
        zcoloraxisDropDownLabel         matlab.ui.control.Label
        optZaxis                        matlab.ui.control.DropDown
        LMS_1Tab                        matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        LightwaveMeasurementSystem01Label  matlab.ui.control.Label
        ConnectionPanel                 matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        panel01_InstrumentDropDownLabel  matlab.ui.control.Label
        panel01_InstrSelect             matlab.ui.control.DropDown
        panel01_ConnectButton           matlab.ui.control.Button
        panel01_instrConnStat           matlab.ui.control.Lamp
        panel01_LaserOutputLampLabel    matlab.ui.control.Label
        panel01_LaserOutputLamp         matlab.ui.control.Lamp
        LaserControlPanel               matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        panel01_WavelengthSpinnerLabel  matlab.ui.control.Label
        panel01_txtWavelength           matlab.ui.control.Spinner
        panel01_OutputPowerSpinnerLabel  matlab.ui.control.Label
        panel01_txtPower                matlab.ui.control.Spinner
        panel01_nmLabel                 matlab.ui.control.Label
        panel01_dBmLabel                matlab.ui.control.Label
        panel01_btnLaserOn              matlab.ui.control.StateButton
        PowerSensorPanel                matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        panel01_AveragingTSpinnerLabel  matlab.ui.control.Label
        panel01_AveragingTSpinner       matlab.ui.control.Spinner
        panel01_RangeDropDownLabel      matlab.ui.control.Label
        panel01_RangeDropDown           matlab.ui.control.DropDown
        panel01_pwrMeas1                matlab.ui.control.Label
        panel01_msLabel                 matlab.ui.control.Label
        panel01_btnPowerScan            matlab.ui.control.StateButton
        panel01_cbAutoRange             matlab.ui.control.CheckBox
        panel01_dBmLabel_2              matlab.ui.control.Label
        panel01_SensorDropDownLabel     matlab.ui.control.Label
        panel01_SensorDropDown          matlab.ui.control.DropDown
        OpticalSwitchPanel              matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        panel01_swAKnob                 matlab.ui.control.DiscreteKnob
        LMS_2Tab                        matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        ConnectionPanel_2               matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        panel02_InstrumentDropDownLabel  matlab.ui.control.Label
        panel02_InstrSelect             matlab.ui.control.DropDown
        panel02_ConnectButton           matlab.ui.control.Button
        panel02_instrConnStat           matlab.ui.control.Lamp
        panel02_LaserOutputLampLabel    matlab.ui.control.Label
        panel02_LaserOutputLamp         matlab.ui.control.Lamp
        LaserControlPanel_2             matlab.ui.container.Panel
        GridLayout9_2                   matlab.ui.container.GridLayout
        panel02_WavelengthSpinnerLabel  matlab.ui.control.Label
        panel02_txtWavelength           matlab.ui.control.Spinner
        panel02_OutputPowerSpinnerLabel  matlab.ui.control.Label
        panel02_txtPower                matlab.ui.control.Spinner
        panel02_nmLabel_2               matlab.ui.control.Label
        panel02_dBmLabel_3              matlab.ui.control.Label
        panel02_btnLaserOn              matlab.ui.control.StateButton
        PowerSensorPanel_2              matlab.ui.container.Panel
        GridLayout10_2                  matlab.ui.container.GridLayout
        panel02_AveragingTSpinnerLabel  matlab.ui.control.Label
        panel02_AveragingTSpinner       matlab.ui.control.Spinner
        panel02_RangeDropDownLabel      matlab.ui.control.Label
        panel02_RangeDropDown           matlab.ui.control.DropDown
        panel02_pwrMeas                 matlab.ui.control.Label
        panel02_msLabel                 matlab.ui.control.Label
        panel02_btnPowerScan            matlab.ui.control.StateButton
        panel02_cbAutoRange             matlab.ui.control.CheckBox
        panel02_dBmLabel                matlab.ui.control.Label
        panel02_SensorDropDownLabel     matlab.ui.control.Label
        panel02_SensorDropDown          matlab.ui.control.DropDown
        OpticalSwitchPanel_2            matlab.ui.container.Panel
        GridLayout11_2                  matlab.ui.container.GridLayout
        panel02_swAKnob                 matlab.ui.control.DiscreteKnob
        LightwaveMeasurementSystem02Label  matlab.ui.control.Label
        SweepTab                        matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        SweepParametersLabel            matlab.ui.control.Label
        ScanParametersPanel             matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        ScanStartSpinnerLabel           matlab.ui.control.Label
        spinStart                       matlab.ui.control.Spinner
        ScanStopSpinnerLabel            matlab.ui.control.Label
        spinStop                        matlab.ui.control.Spinner
        StepSizeSpinnerLabel            matlab.ui.control.Label
        spinStep                        matlab.ui.control.Spinner
        ScanSpeedLabel                  matlab.ui.control.Label
        spinSpeed                       matlab.ui.control.Spinner
        RangeLabel                      matlab.ui.control.Label
        RangeDropDown_Sweep             matlab.ui.control.DropDown
        nmLabel_3                       matlab.ui.control.Label
        nmLabel_4                       matlab.ui.control.Label
        nmLabel_5                       matlab.ui.control.Label
        nmsLabel                        matlab.ui.control.Label
        DataAnalysisTab                 matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        Peakfinder1RedPanel             matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        HeightSpinnerLabel              matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        ProminenceSpinnerLabel          matlab.ui.control.Label
        ProminenceSpinner               matlab.ui.control.Spinner
        MaxWidthSpinnerLabel            matlab.ui.control.Label
        MaxWidthSpinner                 matlab.ui.control.Spinner
        MinWidthSpinnerLabel            matlab.ui.control.Label
        MinWidthSpinner                 matlab.ui.control.Spinner
        PeakSpacingLabel                matlab.ui.control.Label
        DistanceSpinner                 matlab.ui.control.Spinner
        dBmLabel_5                      matlab.ui.control.Label
        nmLabel_6                       matlab.ui.control.Label
        nmLabel_7                       matlab.ui.control.Label
        nmLabel_8                       matlab.ui.control.Label
        MinPeakHeightCheckBox           matlab.ui.control.CheckBox
        MinPeakProminenceCheckBox       matlab.ui.control.CheckBox
        MaxPeakWidthCheckBox            matlab.ui.control.CheckBox
        MinPeakWidthCheckBox            matlab.ui.control.CheckBox
        MinPeakDistanceCheckBox         matlab.ui.control.CheckBox
        InterferenceAnalysisPanel       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        LnmLabel                        matlab.ui.control.Label
        txtResL                         matlab.ui.control.NumericEditField
        TypeofdeviceDropDownLabel       matlab.ui.control.Label
        optTOD                          matlab.ui.control.DropDown
        neffectiveEditFieldLabel        matlab.ui.control.Label
        txtneff                         matlab.ui.control.NumericEditField
        DataAnalysisParametersLabel     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        tblResonanceAnalysis            matlab.ui.control.Table
        btnRunSweep                     matlab.ui.control.StateButton
        FindPeaksButton                 matlab.ui.control.Button
        DisplayPeaksCheckBox            matlab.ui.control.CheckBox
        logger                          matlab.ui.control.TextArea
        UIAxes01                        matlab.ui.control.UIAxes
        FileMenu                        matlab.ui.container.Menu
        ConnectionSetupMenu_2           matlab.ui.container.Menu
        SweepMenu                       matlab.ui.container.Menu
        SweepSave                       matlab.ui.container.Menu
        SweepOpen                       matlab.ui.container.Menu
        ProjectMenu                     matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ProjectSave                     matlab.ui.container.Menu
        ProjectNew                      matlab.ui.container.Menu
        ProjectOpen                     matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        EffectiveIndexCalculationMenu   matlab.ui.container.Menu
        ConnectionSetupMenu             matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        connection                 
    end
    
    properties (Access = private)
        Timer       % Timer for real time measurements
        COM_config  % Communication configuration data
        
        connPanel01 % Description
        LockLaser   % Description
        saveFolder  % Description
        mData       % Description
        
        measurement % Structure with members {Pseudrandom Identification (), Sweep Parameters, Spectra, Analysis Parameters, Peak Analysis results}
        project     % Structure with measurements of type sweep {Pseudrandom Identification (), Sweep Parameters, Spectra, Analysis Parameters, Peak Analysis results}
        selectedRow % Row selected on the measurements tableUI
    end
    
    methods (Access = private)
        
        function setWorkspace(app, configData)
            %Sets the updated data in the data table
            configData.Properties.RowNames = string(configData.ID);
            app.COM_config = configData;
            
            %Fill the combo boxes with the instruments available in the
            %configuration table
            j=1;
            comboStrings = [""];
            for i=1:size(configData,1)
                if configData.Type(i)=="Mainframe"
                    comboStrings(j) = strcat(string(configData.ID(i)),'::',configData.Name(i));
                    comboValues(j) = configData.ID(i);
                    j=j+1;
                end
            end
            
            
            app.panel01_InstrSelect.Items = comboStrings;
            app.panel01_InstrSelect.ItemsData = comboValues;
            app.panel02_InstrSelect.Items = comboStrings;
            app.panel02_InstrSelect.ItemsData = comboValues;
            
            
            %Define the instrument connections 
            
            %%Change this to Insturment TYPE
            app.connection = struct('config',[],'ID',[],'type',[],'instr',[],'status',[]);
            for i =1:size(configData,1)
                
                c = configData(i,:);
                
                app.connection(i).config = c;
                app.connection(i).ID = string(c.ID);
                app.connection(i).type =  c.Interface;
                
                if (app.connection(i).type=='GPIB')
                    app.connection(i).instr = sm_instrument(string(c.vendor), c.Board, c.GPIB);
                    app.connection(i).status = false;
                elseif (app.connection(i).type == 'TCPIP')
                    try
                        app.connection(i).instr = sm_instrument(char(c.("IP Address")));
                        %app.connection(i).tcp = tcpclient( char(c.("IP Address")), 5025,'Timeout',1, 'ConnectionTimeout',1);
                    catch Em
                        app.connection(i).status = false;    
                    end
                    app.connection(i).status = true; 
                else
                    app.connection(i).instr = [];
                    app.connection(i).status = false;
                end
            end
            
            app.panel01_InstrSelect.Items = comboStrings;
            app.panel01_InstrSelect.ItemsData = comboValues;
            app.panel02_InstrSelect.Items = comboStrings;
            app.panel02_InstrSelect.ItemsData = comboValues;
            
            
            % If an instrument is a mainframe, query its slots and get each
            % of the available sensor, and make a list with all sensors
            % available. Then populate the sensor combo boxes.
            
            c_pm = 0; pm_list = [];
            comboStrings = []; comboValues=[];
            model_list = {'7744A','81635A','81618A'}; %"Exhasutive" list of power meters
             
             
            for i =1:size(app.connection,1)
                c = app.connection(i);
                g = c.instr;
                if (c.config.Type=='Mainframe')
                    numslots = 5;
                    for j=1:numslots
                        s = send(g,"SLOT"+(j-1)+":IDN?");
                        
                        stream = strtrim(strsplit(s,','));
                        model = [];
                        if length(stream)>1
                            model = char(stream(2));
                        end
                        
                        if any(strcmp(model_list,model))
                            c_pm = c_pm +1;
                            sens = [];
                            
                            comboStrings(c_pm) = strcat(string(configData.ID(i)),'::',model,'::',num2str(sens));
                            
                            comboValues(j) = [configData.ID(i),sens];
                           
                        end
                        
                       %TO DO Define children instruments for each member
                        
                    end
                elseif c.Type=='Power Meter'
                    c_pm = c_pm +1;
                    comboStrings(c_pm) = strcat(string(configData.ID(i)),'-',model);
                    comboValues(j) = configData.ID(i);
                end
            end
            
            
            %move this out of thios function
                        %Initilize an empty project variable
                        app.project.measurements = {};
                        app.project.filename = '';
                        ID = zeros(0,1); Var1 = ID; Var2 = ID; Var3 = ID; Date = strings(0,1); %Initialize data for the table
                        t = table(ID,Date,Var1,Var2,Var3);
                        app.tblMeasurements.Data = t;
            
            %Set the default save directory
            app.saveFolder = "C:\Users\Public\Documents\Shared Data\Users\";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(pwd+"/dependencies")
            
            % Clear any exisiting GPIB instrument.
            i = instrfindall;
            if  ~isempty(i), fclose(i);instrreset; end

            %Load configuration options
            t = load('config.mat','configData');
            configData = t.configData;
           
            %Configure the timer for the power meter reading.
            app.Timer = timer('Period',0.1,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.Timer.TimerFcn = @app.TimerFcn;
            
            setWorkspace(app, configData)
        end

        % Menu selected function: ConnectionSetupMenu_2
        function menu_connSetup(app, event)
           
            configData = app.COM_config;
            connSetup = ConnectionSetup(configData);
            
            while (~connSetup.done)
                pause(0.1);
                if (~isvalid(connSetup)); break; end
            end
            if (isvalid(connSetup))
                configData = connSetup.dataOut;
                connSetup.close;
            end
            app.COM_config = configData;
            
            setWorkspace(app, configData);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {815, 815};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {415, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.ProjectTab = uitab(app.TabGroup2);
            app.ProjectTab.Title = 'Project';
            app.GridLayout15 = uigridlayout(app.ProjectTab);
            app.MeasurementsPanel = uipanel(app.GridLayout15);
            app.MeasurementsPanel.Title = 'Measurements';
            app.GridLayout19 = uigridlayout(app.MeasurementsPanel);
            app.tblMeasurements = uitable(app.GridLayout19);
            app.tblMeasurements.ColumnName = {'ID'; 'Time'; 'Var 1'; 'Var 2'; 'Var 3'};
            app.tblMeasurements.RowName = {};
            app.tblMeasurements.ColumnSortable = [true true false false false];
            app.tblMeasurements.ColumnEditable = [false false true true true];
            app.btnDeleteMeasurement = uibutton(app.GridLayout19, 'push');
            app.btnDeleteMeasurement.Text = '-';
            app.ProjectManagementLabel = uilabel(app.GridLayout15);
            app.ProjectManagementLabel.Text = 'Project Management';
            app.PlottingPanel = uipanel(app.GridLayout15);
            app.PlottingPanel.Title = 'Plotting';
            app.GridLayout20 = uigridlayout(app.PlottingPanel);
            app.btnPlot = uibutton(app.GridLayout20, 'push');
            app.btnPlot.Text = 'Plot';
            app.xaxisDropDownLabel = uilabel(app.GridLayout20);
            app.xaxisDropDownLabel.Text = 'x axis';
            app.optXaxis = uidropdown(app.GridLayout20);
            app.optXaxis.Items = {'wavelength', 'time', 'var 1', 'var 2', 'var 3'};
            app.optXaxis.Value = 'wavelength';
            app.yaxisDropDownLabel = uilabel(app.GridLayout20);
            app.yaxisDropDownLabel.Text = 'y axis';
            app.optYaxis = uidropdown(app.GridLayout20);
            app.optYaxis.Items = {'wavelength', 'intensity', 'Resonance Wav.', 'FSR', 'FWHM', 'Q. Factor', 'Group Index'};
            app.optYaxis.Value = 'intensity';
            app.zcoloraxisDropDownLabel = uilabel(app.GridLayout20);
            app.zcoloraxisDropDownLabel.Text = 'z (color) axis';
            app.optZaxis = uidropdown(app.GridLayout20);
            app.optZaxis.Items = {'Not used', 'intensity'};
            app.optZaxis.Value = 'Not used';
            app.LMS_1Tab = uitab(app.TabGroup2);
            app.LMS_1Tab.Title = 'LMS_1';
            app.GridLayout13 = uigridlayout(app.LMS_1Tab);
            app.LightwaveMeasurementSystem01Label = uilabel(app.GridLayout13);
            app.LightwaveMeasurementSystem01Label.Text = 'Lightwave Measurement System 01';
            app.ConnectionPanel = uipanel(app.GridLayout13);
            app.ConnectionPanel.Title = 'Connection';
            app.GridLayout8 = uigridlayout(app.ConnectionPanel);
            app.panel01_InstrumentDropDownLabel = uilabel(app.GridLayout8);
            app.panel01_InstrumentDropDownLabel.Text = 'Instrument';
            app.panel01_InstrSelect = uidropdown(app.GridLayout8);
            app.panel01_InstrSelect.ItemsData = {'1001'};
            app.panel01_InstrSelect.Tag = 'intrSelectLbl_1';
            app.panel01_InstrSelect.Value = '1001';
            app.panel01_ConnectButton = uibutton(app.GridLayout8, 'push');
            app.panel01_ConnectButton.Text = 'Connect';
            app.panel01_instrConnStat = uilamp(app.GridLayout8);
            app.panel01_instrConnStat.Enable = 'off';
            app.panel01_LaserOutputLampLabel = uilabel(app.GridLayout8);
            app.panel01_LaserOutputLampLabel.Text = 'Laser Output';
            app.panel01_LaserOutputLamp = uilamp(app.GridLayout8);
            app.panel01_LaserOutputLamp.Enable = 'off';
            app.LaserControlPanel = uipanel(app.GridLayout13);
            app.LaserControlPanel.Title = 'Laser Control';
            app.GridLayout9 = uigridlayout(app.LaserControlPanel);
            app.panel01_WavelengthSpinnerLabel = uilabel(app.GridLayout9);
            app.panel01_WavelengthSpinnerLabel.Text = 'Wavelength';
            app.panel01_txtWavelength = uispinner(app.GridLayout9);
            app.panel01_txtWavelength.Step = 0.1;
            app.panel01_txtWavelength.Limits = [1500 1640];
            app.panel01_txtWavelength.ValueDisplayFormat = '%.3f';
            app.panel01_txtWavelength.Value = 1550;
            app.panel01_OutputPowerSpinnerLabel = uilabel(app.GridLayout9);
            app.panel01_OutputPowerSpinnerLabel.Text = 'Output Power';
            app.panel01_txtPower = uispinner(app.GridLayout9);
            app.panel01_txtPower.Step = 0.1;
            app.panel01_txtPower.Limits = [0 13];
            app.panel01_txtPower.ValueDisplayFormat = '%5.2f';
            app.panel01_txtPower.Value = 3;
            app.panel01_nmLabel = uilabel(app.GridLayout9);
            app.panel01_nmLabel.Text = 'nm';
            app.panel01_dBmLabel = uilabel(app.GridLayout9);
            app.panel01_dBmLabel.Text = 'dBm';
            app.panel01_btnLaserOn = uibutton(app.GridLayout9, 'state');
            app.panel01_btnLaserOn.Enable = 'off';
            app.panel01_btnLaserOn.Text = 'On';
            app.PowerSensorPanel = uipanel(app.GridLayout13);
            app.PowerSensorPanel.Title = 'Power Sensor';
            app.GridLayout10 = uigridlayout(app.PowerSensorPanel);
            app.panel01_AveragingTSpinnerLabel = uilabel(app.GridLayout10);
            app.panel01_AveragingTSpinnerLabel.Text = 'Averaging T';
            app.panel01_AveragingTSpinner = uispinner(app.GridLayout10);
            app.panel01_AveragingTSpinner.Limits = [1 1000];
            app.panel01_AveragingTSpinner.ValueDisplayFormat = '%5.2f';
            app.panel01_AveragingTSpinner.Value = 200;
            app.panel01_RangeDropDownLabel = uilabel(app.GridLayout10);
            app.panel01_RangeDropDownLabel.Enable = 'off';
            app.panel01_RangeDropDownLabel.Text = 'Range';
            app.panel01_RangeDropDown = uidropdown(app.GridLayout10);
            app.panel01_RangeDropDown.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.panel01_RangeDropDown.ItemsData = {'40DBM', '30DBM', '20DBM', '10DBM', '0DBM', '-10DBM', '-20DBM', '-30DBM', '-40DBM', '-50DBM', '-60DBM', '-70DBM'};
            app.panel01_RangeDropDown.Enable = 'off';
            app.panel01_RangeDropDown.Value = '-20DBM';
            app.panel01_pwrMeas1 = uilabel(app.GridLayout10);
            app.panel01_pwrMeas1.Text = '0.00';
            app.panel01_msLabel = uilabel(app.GridLayout10);
            app.panel01_msLabel.Text = 'ms';
            app.panel01_btnPowerScan = uibutton(app.GridLayout10, 'state');
            app.panel01_btnPowerScan.Enable = 'off';
            app.panel01_btnPowerScan.Text = 'Run';
            app.panel01_cbAutoRange = uicheckbox(app.GridLayout10);
            app.panel01_cbAutoRange.Text = 'Auto';
            app.panel01_cbAutoRange.Value = true;
            app.panel01_dBmLabel_2 = uilabel(app.GridLayout10);
            app.panel01_dBmLabel_2.Text = 'dBm';
            app.panel01_SensorDropDownLabel = uilabel(app.GridLayout10);
            app.panel01_SensorDropDownLabel.Text = 'Sensor';
            app.panel01_SensorDropDown = uidropdown(app.GridLayout10);
            app.OpticalSwitchPanel = uipanel(app.GridLayout13);
            app.OpticalSwitchPanel.Title = 'Optical Switch';
            app.GridLayout11 = uigridlayout(app.OpticalSwitchPanel);
            app.panel01_swAKnob = uiknob(app.GridLayout11, 'discrete');
            app.panel01_swAKnob.Items = {'1', '2', '3', '4'};
            app.panel01_swAKnob.Enable = 'off';
            app.panel01_swAKnob.Value = '1';
            app.LMS_2Tab = uitab(app.TabGroup2);
            app.LMS_2Tab.Title = 'LMS_2';
            app.GridLayout14 = uigridlayout(app.LMS_2Tab);
            app.ConnectionPanel_2 = uipanel(app.GridLayout14);
            app.ConnectionPanel_2.Title = 'Connection';
            app.GridLayout8_2 = uigridlayout(app.ConnectionPanel_2);
            app.panel02_InstrumentDropDownLabel = uilabel(app.GridLayout8_2);
            app.panel02_InstrumentDropDownLabel.Tag = 'intrSelectLbl_2';
            app.panel02_InstrumentDropDownLabel.Text = 'Instrument';
            app.panel02_InstrSelect = uidropdown(app.GridLayout8_2);
            app.panel02_InstrSelect.ItemsData = {'1001'};
            app.panel02_InstrSelect.Value = '1001';
            app.panel02_ConnectButton = uibutton(app.GridLayout8_2, 'push');
            app.panel02_ConnectButton.Text = 'Connect';
            app.panel02_instrConnStat = uilamp(app.GridLayout8_2);
            app.panel02_instrConnStat.Enable = 'off';
            app.panel02_LaserOutputLampLabel = uilabel(app.GridLayout8_2);
            app.panel02_LaserOutputLampLabel.Text = 'Laser Output';
            app.panel02_LaserOutputLamp = uilamp(app.GridLayout8_2);
            app.panel02_LaserOutputLamp.Enable = 'off';
            app.LaserControlPanel_2 = uipanel(app.GridLayout14);
            app.LaserControlPanel_2.Title = 'Laser Control';
            app.GridLayout9_2 = uigridlayout(app.LaserControlPanel_2);
            app.panel02_WavelengthSpinnerLabel = uilabel(app.GridLayout9_2);
            app.panel02_WavelengthSpinnerLabel.Text = 'Wavelength';
            app.panel02_txtWavelength = uispinner(app.GridLayout9_2);
            app.panel02_txtWavelength.Step = 0.1;
            app.panel02_txtWavelength.Limits = [1500 1640];
            app.panel02_txtWavelength.ValueDisplayFormat = '%.3f';
            app.panel02_txtWavelength.Value = 1550;
            app.panel02_OutputPowerSpinnerLabel = uilabel(app.GridLayout9_2);
            app.panel02_OutputPowerSpinnerLabel.Text = 'Output Power';
            app.panel02_txtPower = uispinner(app.GridLayout9_2);
            app.panel02_txtPower.Step = 0.1;
            app.panel02_txtPower.Limits = [0 13];
            app.panel02_txtPower.ValueDisplayFormat = '%5.2f';
            app.panel02_txtPower.Value = 3;
            app.panel02_nmLabel_2 = uilabel(app.GridLayout9_2);
            app.panel02_nmLabel_2.Text = 'nm';
            app.panel02_dBmLabel_3 = uilabel(app.GridLayout9_2);
            app.panel02_dBmLabel_3.Text = 'dBm';
            app.panel02_btnLaserOn = uibutton(app.GridLayout9_2, 'state');
            app.panel02_btnLaserOn.Enable = 'off';
            app.panel02_btnLaserOn.Text = 'On';
            app.PowerSensorPanel_2 = uipanel(app.GridLayout14);
            app.PowerSensorPanel_2.Title = 'Power Sensor';
            app.GridLayout10_2 = uigridlayout(app.PowerSensorPanel_2);
            app.panel02_AveragingTSpinnerLabel = uilabel(app.GridLayout10_2);
            app.panel02_AveragingTSpinnerLabel.Text = 'Averaging T';
            app.panel02_AveragingTSpinner = uispinner(app.GridLayout10_2);
            app.panel02_AveragingTSpinner.Limits = [1 1000];
            app.panel02_AveragingTSpinner.ValueDisplayFormat = '%5.2f';
            app.panel02_AveragingTSpinner.Value = 200;
            app.panel02_RangeDropDownLabel = uilabel(app.GridLayout10_2);
            app.panel02_RangeDropDownLabel.Enable = 'off';
            app.panel02_RangeDropDownLabel.Text = 'Range';
            app.panel02_RangeDropDown = uidropdown(app.GridLayout10_2);
            app.panel02_RangeDropDown.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.panel02_RangeDropDown.ItemsData = {'40DBM', '30DBM', '20DBM', '10DBM', '0DBM', '-10DBM', '-20DBM', '-30DBM', '-40DBM', '-50DBM', '-60DBM', '-70DBM'};
            app.panel02_RangeDropDown.Enable = 'off';
            app.panel02_RangeDropDown.Value = '-20DBM';
            app.panel02_pwrMeas = uilabel(app.GridLayout10_2);
            app.panel02_pwrMeas.Text = '0.00';
            app.panel02_msLabel = uilabel(app.GridLayout10_2);
            app.panel02_msLabel.Text = 'ms';
            app.panel02_btnPowerScan = uibutton(app.GridLayout10_2, 'state');
            app.panel02_btnPowerScan.Enable = 'off';
            app.panel02_btnPowerScan.Text = 'Run';
            app.panel02_cbAutoRange = uicheckbox(app.GridLayout10_2);
            app.panel02_cbAutoRange.Text = 'Auto';
            app.panel02_cbAutoRange.Value = true;
            app.panel02_dBmLabel = uilabel(app.GridLayout10_2);
            app.panel02_dBmLabel.Text = 'dBm';
            app.panel02_SensorDropDownLabel = uilabel(app.GridLayout10_2);
            app.panel02_SensorDropDownLabel.Text = 'Sensor';
            app.panel02_SensorDropDown = uidropdown(app.GridLayout10_2);
            app.OpticalSwitchPanel_2 = uipanel(app.GridLayout14);
            app.OpticalSwitchPanel_2.Title = 'Optical Switch';
            app.GridLayout11_2 = uigridlayout(app.OpticalSwitchPanel_2);
            app.panel02_swAKnob = uiknob(app.GridLayout11_2, 'discrete');
            app.panel02_swAKnob.Items = {'1', '2', '3', '4'};
            app.panel02_swAKnob.Enable = 'off';
            app.panel02_swAKnob.Value = '1';
            app.LightwaveMeasurementSystem02Label = uilabel(app.GridLayout14);
            app.LightwaveMeasurementSystem02Label.Text = 'Lightwave Measurement System 02';
            app.SweepTab = uitab(app.TabGroup2);
            app.SweepTab.Title = 'Sweep';
            app.GridLayout17 = uigridlayout(app.SweepTab);
            app.SweepParametersLabel = uilabel(app.GridLayout17);
            app.SweepParametersLabel.Text = 'Sweep Parameters';
            app.ScanParametersPanel = uipanel(app.GridLayout17);
            app.ScanParametersPanel.Title = 'Scan Parameters';
            app.GridLayout18 = uigridlayout(app.ScanParametersPanel);
            app.ScanStartSpinnerLabel = uilabel(app.GridLayout18);
            app.ScanStartSpinnerLabel.Text = 'Scan Start';
            app.spinStart = uispinner(app.GridLayout18);
            app.spinStart.Limits = [1500 1640];
            app.spinStart.Value = 1520;
            app.ScanStopSpinnerLabel = uilabel(app.GridLayout18);
            app.ScanStopSpinnerLabel.Text = 'Scan Stop';
            app.spinStop = uispinner(app.GridLayout18);
            app.spinStop.Limits = [1500 1640];
            app.spinStop.Value = 1580;
            app.StepSizeSpinnerLabel = uilabel(app.GridLayout18);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.spinStep = uispinner(app.GridLayout18);
            app.spinStep.Step = 0.01;
            app.spinStep.Limits = [0.01 1];
            app.spinStep.Value = 0.1;
            app.ScanSpeedLabel = uilabel(app.GridLayout18);
            app.ScanSpeedLabel.Text = 'Scan Speed';
            app.spinSpeed = uispinner(app.GridLayout18);
            app.spinSpeed.Step = 0.1;
            app.spinSpeed.Limits = [0.1 10];
            app.spinSpeed.ValueDisplayFormat = '%2.1f';
            app.spinSpeed.Value = 1;
            app.RangeLabel = uilabel(app.GridLayout18);
            app.RangeLabel.Text = 'Range';
            app.RangeDropDown_Sweep = uidropdown(app.GridLayout18);
            app.RangeDropDown_Sweep.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.RangeDropDown_Sweep.ItemsData = {'40', '30', '20', '10', '0', '-10', '-20', '-30', '-40', '-50', '-60', '-70'};
            app.RangeDropDown_Sweep.Value = '-10';
            app.nmLabel_3 = uilabel(app.GridLayout18);
            app.nmLabel_3.Text = 'nm';
            app.nmLabel_4 = uilabel(app.GridLayout18);
            app.nmLabel_4.Text = 'nm';
            app.nmLabel_5 = uilabel(app.GridLayout18);
            app.nmLabel_5.Text = 'nm';
            app.nmsLabel = uilabel(app.GridLayout18);
            app.nmsLabel.Text = 'nm/s';
            app.DataAnalysisTab = uitab(app.TabGroup2);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.GridLayout16 = uigridlayout(app.DataAnalysisTab);
            app.Peakfinder1RedPanel = uipanel(app.GridLayout16);
            app.Peakfinder1RedPanel.Title = 'Peak finder 1 (Red)';
            app.GridLayout7 = uigridlayout(app.Peakfinder1RedPanel);
            app.HeightSpinnerLabel = uilabel(app.GridLayout7);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout7);
            app.HeightSpinner.Value = -35;
            app.ProminenceSpinnerLabel = uilabel(app.GridLayout7);
            app.ProminenceSpinnerLabel.Text = 'Prominence';
            app.ProminenceSpinner = uispinner(app.GridLayout7);
            app.ProminenceSpinner.Value = 15;
            app.MaxWidthSpinnerLabel = uilabel(app.GridLayout7);
            app.MaxWidthSpinnerLabel.Text = 'Max Width';
            app.MaxWidthSpinner = uispinner(app.GridLayout7);
            app.MaxWidthSpinner.Value = 0.5;
            app.MinWidthSpinnerLabel = uilabel(app.GridLayout7);
            app.MinWidthSpinnerLabel.Text = 'Min Width';
            app.MinWidthSpinner = uispinner(app.GridLayout7);
            app.PeakSpacingLabel = uilabel(app.GridLayout7);
            app.PeakSpacingLabel.Text = 'Peak Spacing';
            app.DistanceSpinner = uispinner(app.GridLayout7);
            app.DistanceSpinner.Value = 3;
            app.dBmLabel_5 = uilabel(app.GridLayout7);
            app.dBmLabel_5.Text = 'dBm';
            app.nmLabel_6 = uilabel(app.GridLayout7);
            app.nmLabel_6.Text = 'nm';
            app.nmLabel_7 = uilabel(app.GridLayout7);
            app.nmLabel_7.Text = 'nm';
            app.nmLabel_8 = uilabel(app.GridLayout7);
            app.nmLabel_8.Text = 'nm';
            app.MinPeakHeightCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakHeightCheckBox.Text = '';
            app.MinPeakProminenceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakProminenceCheckBox.Text = '';
            app.MinPeakProminenceCheckBox.Value = true;
            app.MaxPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MaxPeakWidthCheckBox.Text = '';
            app.MinPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakWidthCheckBox.Text = '';
            app.MinPeakDistanceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakDistanceCheckBox.Text = '';
            app.InterferenceAnalysisPanel = uipanel(app.GridLayout16);
            app.InterferenceAnalysisPanel.Title = 'Interference Analysis';
            app.GridLayout3 = uigridlayout(app.InterferenceAnalysisPanel);
            app.LnmLabel = uilabel(app.GridLayout3);
            app.LnmLabel.Text = 'L (nm)';
            app.txtResL = uieditfield(app.GridLayout3, 'numeric');
            app.txtResL.Value = 62831;
            app.TypeofdeviceDropDownLabel = uilabel(app.GridLayout3);
            app.TypeofdeviceDropDownLabel.Text = 'Type of device';
            app.optTOD = uidropdown(app.GridLayout3);
            app.optTOD.Items = {'Resonator', 'Interferometer', 'Other'};
            app.optTOD.Value = 'Resonator';
            app.neffectiveEditFieldLabel = uilabel(app.GridLayout3);
            app.neffectiveEditFieldLabel.Text = 'n effective ';
            app.txtneff = uieditfield(app.GridLayout3, 'numeric');
            app.txtneff.Limits = [1 50];
            app.txtneff.Value = 3.4;
            app.DataAnalysisParametersLabel = uilabel(app.GridLayout16);
            app.DataAnalysisParametersLabel.Text = 'Data Analysis Parameters';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'logo.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout21 = uigridlayout(app.RightPanel);
            app.tblResonanceAnalysis = uitable(app.GridLayout21);
            app.tblResonanceAnalysis.ColumnName = {'Peak'; 'Wavelength'; 'FSR'; 'FWHM'; 'Q. Factor'; 'Group Index'; 'Loss Coeficient'};
            app.tblResonanceAnalysis.RowName = {};
            app.btnRunSweep = uibutton(app.GridLayout21, 'state');
            app.btnRunSweep.Enable = 'off';
            app.btnRunSweep.Text = 'Run Sweep';
            app.FindPeaksButton = uibutton(app.GridLayout21, 'push');
            app.FindPeaksButton.Text = 'Find Peaks';
            app.DisplayPeaksCheckBox = uicheckbox(app.GridLayout21);
            app.DisplayPeaksCheckBox.Text = 'Display Peaks';
            app.logger = uitextarea(app.GridLayout21);
            app.logger.Value = {'Spectral Measurements 1.3  (C) NYU PRL 2020'};
            app.UIAxes01 = uiaxes(app.GridLayout21);
            title(app.UIAxes01, 'Spectrum')
            xlabel(app.UIAxes01, 'wavelength (m)')
            ylabel(app.UIAxes01, 'intensity (dBm)')
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ConnectionSetupMenu_2 = uimenu(app.FileMenu);
            app.ConnectionSetupMenu_2.MenuSelectedFcn = createCallbackFcn(app, @menu_connSetup, true);
            app.ConnectionSetupMenu_2.Text = 'Connection Setup';
            app.SweepMenu = uimenu(app.FileMenu);
            app.SweepMenu.Text = 'Sweep';
            app.SweepSave = uimenu(app.SweepMenu);
            app.SweepSave.Text = 'Export';
            app.SweepOpen = uimenu(app.SweepMenu);
            app.SweepOpen.Text = 'Import';
            app.ProjectMenu = uimenu(app.FileMenu);
            app.ProjectMenu.Text = 'Project';
            app.SaveMenu = uimenu(app.ProjectMenu);
            app.SaveMenu.Text = 'Save';
            app.ProjectSave = uimenu(app.ProjectMenu);
            app.ProjectSave.Text = 'Save As';
            app.ProjectNew = uimenu(app.ProjectMenu);
            app.ProjectNew.Text = 'New';
            app.ProjectOpen = uimenu(app.ProjectMenu);
            app.ProjectOpen.Text = 'Open';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.EffectiveIndexCalculationMenu = uimenu(app.ToolsMenu);
            app.EffectiveIndexCalculationMenu.Text = 'Effective Index Calculation';
            app.ConnectionSetupMenu = uimenu(app.ToolsMenu);
            app.ConnectionSetupMenu.Text = 'Connection Setup';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end